classdef MyBalance < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MyBalance              matlab.ui.Figure
        BalanceEditFieldLabel  matlab.ui.control.Label
        ViewBalance            matlab.ui.control.NumericEditField
        RMBEditFieldLabel      matlab.ui.control.Label
        ViewRMB                matlab.ui.control.NumericEditField
        WithdrawButton         matlab.ui.control.Button
        DepositButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        Balance double % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Balance=500;
            app.ViewRMB.Value=0;
            app.ViewBalance.Value=app.Balance;
        end

        % Button pushed function: WithdrawButton
        function WithdrawButtonPushed(app, event)
            value=app.ViewRMB.Value;
            app.Balance=app.Balance-value;
            app.ViewBalance.Value=app.Balance;
        end

        % Button pushed function: DepositButton
        function DepositButtonPushed(app, event)
            value=app.ViewRMB.Value;
            app.Balance=app.Balance+value;
            app.ViewBalance.Value=app.Balance;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MyBalance = uifigure;
            app.MyBalance.Name = '账户';
            app.BalanceEditFieldLabel = uilabel(app.MyBalance);
            app.BalanceEditFieldLabel.Text = 'Balance';
            app.ViewBalance = uieditfield(app.MyBalance, 'numeric');
            app.RMBEditFieldLabel = uilabel(app.MyBalance);
            app.RMBEditFieldLabel.Text = 'RMB';
            app.ViewRMB = uieditfield(app.MyBalance, 'numeric');
            app.WithdrawButton = uibutton(app.MyBalance, 'push');
            app.WithdrawButton.ButtonPushedFcn = createCallbackFcn(app, @WithdrawButtonPushed, true);
            app.WithdrawButton.Text = 'Withdraw';
            app.DepositButton = uibutton(app.MyBalance, 'push');
            app.DepositButton.ButtonPushedFcn = createCallbackFcn(app, @DepositButtonPushed, true);
            app.DepositButton.Text = 'Deposit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MyBalance

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MyBalance)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MyBalance)
        end
    end
end