classdef sigVsysLab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        FatherTabGroup     matlab.ui.container.TabGroup
        SigTimOpTab        matlab.ui.container.Tab
        SigTimOpTabGroup   matlab.ui.container.TabGroup
        ConTimOpTab        matlab.ui.container.Tab
        UIAxes             matlab.ui.control.UIAxes
        DisTimOpTab        matlab.ui.container.Tab
        ConConvTab         matlab.ui.container.Tab
        ConConvTabGroup    matlab.ui.container.TabGroup
        ConConvIntTab      matlab.ui.container.Tab
        RCCasTab           matlab.ui.container.Tab
        RCParTab           matlab.ui.container.Tab
        DisConvTab         matlab.ui.container.Tab
        DisConvTabGroup    matlab.ui.container.TabGroup
        DisConvSumTab      matlab.ui.container.Tab
        DisDifTab          matlab.ui.container.Tab
        SigFreAnaTab       matlab.ui.container.Tab
        SigFreAnaTabGroup  matlab.ui.container.TabGroup
        CFSTab             matlab.ui.container.Tab
        Orig41UIAxes       matlab.ui.control.UIAxes
        Harm41UIAxes       matlab.ui.control.UIAxes
        Synth41UIAxes      matlab.ui.control.UIAxes
        Label              matlab.ui.control.Label
        SelSig41DropDown   matlab.ui.control.DropDown
        Label_2            matlab.ui.control.Label
        HarmNum41Slider    matlab.ui.control.Slider
        HarmNum41Label     matlab.ui.control.Label
        Amp41Label         matlab.ui.control.Label
        Label_6            matlab.ui.control.Label
        Amp41Slider        matlab.ui.control.Slider
        Peri41Label        matlab.ui.control.Label
        Label_7            matlab.ui.control.Label
        Peri41Slider       matlab.ui.control.Slider
        CFTTab             matlab.ui.container.Tab
        DFTTab             matlab.ui.container.Tab
        ConSamTab          matlab.ui.container.Tab
        SysFreAnaTab       matlab.ui.container.Tab
        SysFreAnaTabGroup  matlab.ui.container.TabGroup
        ConFreAnaTab       matlab.ui.container.Tab
        DisFreAnaTab       matlab.ui.container.Tab
    end

    
    properties (Access = public)
        amplitude41 double % 幅度
        period41 double % 周期
        harmNum41 int16 % 谐波分量个数
        wavFormChoose41 string% 波形选择
    end
    
    methods (Access = public)
        
        function toggleReplot41(app)
%             标签显示值修改，全局变量值更新
            app.wavFormChoose41 = app.SelSig41DropDown.Value;
            app.harmNum41 = int16(app.HarmNum41Slider.Value);
            app.HarmNum41Label.Text=num2str(app.harmNum41);
            app.amplitude41 = app.Amp41Slider.Value;
            app.Amp41Label.Text=num2str(app.amplitude41);
            app.period41 = app.Peri41Slider.Value;
            app.Peri41Label.Text=num2str(app.period41);
%             画原始信号
            tt41=0:0.1:100;      
            switch app.wavFormChoose41
                case '方波'
                    xorigtt41=square((2*pi*tt41)/app.period41)*app.amplitude41;
                case '三角波'
                    xorigtt41=sawtooth(2*pi*tt41/app.period41,0.5)*app.amplitude41;
                case '锯齿波'
                    xorigtt41=sawtooth(2*pi*tt41/app.period41)*app.amplitude41;
            end
            plot(app.Orig41UIAxes,tt41,xorigtt41);
            
            kk41=0:399;
            switch app.wavFormChoose41
                case '方波'
                    akorigkk41=fft(square(2*pi*kk41/400)*app.amplitude41);
                case '三角波'
                    akorigkk41=fft(sawtooth(2*pi*tt41/400,0.5)*app.amplitude41);
                case '锯齿波'
                    akorigkk41=fft(sawtooth(2*pi*tt41/400)*app.amplitude41);   
            end
            ak2kk41=abs([akorigkk41(202:400),akorigkk41(1:200)]);
            ak3kk41=ak2kk41/400;
            akk41=ak3kk41(200-app.harmNum41:200+app.harmNum41);
            stem(app.Harm41UIAxes,-app.harmNum41:app.harmNum41,akk41,'MarkerSize',2,'LineWidth',0.05);


        end
    end
    

    methods (Access = private)

        % Value changed function: SelSig41DropDown
        function SelSig41DropDownValueChanged(app, event)
          toggleReplot41(app);
            
        end

        % Value changed function: HarmNum41Slider
        function HarmNum41SliderValueChanged(app, event)
            toggleReplot41(app);
            
        end

        % Value changed function: Amp41Slider
        function Amp41SliderValueChanged(app, event)
            toggleReplot41(app);

        end

        % Value changed function: Peri41Slider
        function Peri41SliderValueChanged(app, event)
            toggleReplot41(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = '华中科技大学电信学院信号系统实验平台';
            app.FatherTabGroup = uitabgroup(app.UIFigure);
            app.SigTimOpTab = uitab(app.FatherTabGroup);
            app.SigTimOpTab.Title = '信号时域运算';
            app.SigTimOpTabGroup = uitabgroup(app.SigTimOpTab);
            app.ConTimOpTab = uitab(app.SigTimOpTabGroup);
            app.ConTimOpTab.Title = '连续信号时域运算';
            app.UIAxes = uiaxes(app.ConTimOpTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.DisTimOpTab = uitab(app.SigTimOpTabGroup);
            app.DisTimOpTab.Title = '离散信号时域运算';
            app.ConConvTab = uitab(app.FatherTabGroup);
            app.ConConvTab.Title = '连续信号卷积与系统时域分析';
            app.ConConvTabGroup = uitabgroup(app.ConConvTab);
            app.ConConvIntTab = uitab(app.ConConvTabGroup);
            app.ConConvIntTab.Title = '连续信号卷积积分';
            app.RCCasTab = uitab(app.ConConvTabGroup);
            app.RCCasTab.Title = 'RC串联电路时域分析';
            app.RCParTab = uitab(app.ConConvTabGroup);
            app.RCParTab.Title = 'RC并联电路时域分析';
            app.DisConvTab = uitab(app.FatherTabGroup);
            app.DisConvTab.Title = '离散信号卷积与系统时域分析';
            app.DisConvTabGroup = uitabgroup(app.DisConvTab);
            app.DisConvSumTab = uitab(app.DisConvTabGroup);
            app.DisConvSumTab.Title = '离散信号卷积求和';
            app.DisDifTab = uitab(app.DisConvTabGroup);
            app.DisDifTab.Title = '离散差分方程求解';
            app.SigFreAnaTab = uitab(app.FatherTabGroup);
            app.SigFreAnaTab.Title = '信号频域分析';
            app.SigFreAnaTabGroup = uitabgroup(app.SigFreAnaTab);
            app.CFSTab = uitab(app.SigFreAnaTabGroup);
            app.CFSTab.Title = '连续周期信号FS';
            app.Orig41UIAxes = uiaxes(app.CFSTab);
            title(app.Orig41UIAxes, '原始信号')
            xlabel(app.Orig41UIAxes, 't')
            ylabel(app.Orig41UIAxes, 'xorig(t)')
            app.Orig41UIAxes.XLim = [0 100];
            app.Orig41UIAxes.YLim = [-15 15];
            app.Harm41UIAxes = uiaxes(app.CFSTab);
            title(app.Harm41UIAxes, '谐波分量')
            xlabel(app.Harm41UIAxes, 'k')
            ylabel(app.Harm41UIAxes, 'Ak')
            app.Harm41UIAxes.YLim = [0 7];
            app.Synth41UIAxes = uiaxes(app.CFSTab);
            title(app.Synth41UIAxes, '合成波形')
            xlabel(app.Synth41UIAxes, 't')
            ylabel(app.Synth41UIAxes, 'xsynth(t)')
            app.Synth41UIAxes.XLim = [0 100];
            app.Synth41UIAxes.YLim = [-15 15];
            app.Label = uilabel(app.CFSTab);
            app.Label.Text = '波形选择';
            app.SelSig41DropDown = uidropdown(app.CFSTab);
            app.SelSig41DropDown.Items = {'方波', '锯齿波', '三角波'};
            app.SelSig41DropDown.ValueChangedFcn = createCallbackFcn(app, @SelSig41DropDownValueChanged, true);
            app.SelSig41DropDown.Value = '方波';
            app.Label_2 = uilabel(app.CFSTab);
            app.Label_2.Text = '谐波分量个数';
            app.HarmNum41Slider = uislider(app.CFSTab);
            app.HarmNum41Slider.Limits = [1 199];
            app.HarmNum41Slider.ValueChangedFcn = createCallbackFcn(app, @HarmNum41SliderValueChanged, true);
            app.HarmNum41Slider.Value = 1;
            app.HarmNum41Label = uilabel(app.CFSTab);
            app.Amp41Label = uilabel(app.CFSTab);
            app.Label_6 = uilabel(app.CFSTab);
            app.Label_6.Text = '幅度';
            app.Amp41Slider = uislider(app.CFSTab);
            app.Amp41Slider.Limits = [0 10];
            app.Amp41Slider.ValueChangedFcn = createCallbackFcn(app, @Amp41SliderValueChanged, true);
            app.Peri41Label = uilabel(app.CFSTab);
            app.Label_7 = uilabel(app.CFSTab);
            app.Label_7.Text = '周期';
            app.Peri41Slider = uislider(app.CFSTab);
            app.Peri41Slider.Limits = [5 200];
            app.Peri41Slider.ValueChangedFcn = createCallbackFcn(app, @Peri41SliderValueChanged, true);
            app.Peri41Slider.Value = 5;
            app.CFTTab = uitab(app.SigFreAnaTabGroup);
            app.CFTTab.Title = '连续信号FT';
            app.DFTTab = uitab(app.SigFreAnaTabGroup);
            app.DFTTab.Title = '离散信号FT';
            app.ConSamTab = uitab(app.FatherTabGroup);
            app.ConSamTab.Title = '连续信号采样与恢复';
            app.SysFreAnaTab = uitab(app.FatherTabGroup);
            app.SysFreAnaTab.Title = '系统频域分析';
            app.SysFreAnaTabGroup = uitabgroup(app.SysFreAnaTab);
            app.ConFreAnaTab = uitab(app.SysFreAnaTabGroup);
            app.ConFreAnaTab.Title = '连续系统频域分析';
            app.DisFreAnaTab = uitab(app.SysFreAnaTabGroup);
            app.DisFreAnaTab.Title = '离散系统频域分析';
        end
    end

    methods (Access = public)

        % Construct app
        function app = sigVsysLab

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end