classdef lianxujishu_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        axes1        matlab.ui.control.UIAxes
        axes2        matlab.ui.control.UIAxes
        axes3        matlab.ui.control.UIAxes
        text1        matlab.ui.control.Label
        edit1        matlab.ui.control.EditField
        text5        matlab.ui.control.Label
        text6        matlab.ui.control.Label
        text7        matlab.ui.control.Label
        pushbutton2  matlab.ui.control.Button
        pushbutton3  matlab.ui.control.Button
        edit3        matlab.ui.control.EditField
        edit4        matlab.ui.control.EditField
        edit2        matlab.ui.control.EditField
        text8        matlab.ui.control.Label
        text9        matlab.ui.control.Label
        text10       matlab.ui.control.Label
        text12       matlab.ui.control.Label
        pushbutton1  matlab.ui.control.Button
        popupmenu1   matlab.ui.control.DropDown
        pushbutton7  matlab.ui.control.Button
        text13       matlab.ui.control.Label
        text14       matlab.ui.control.Label
    end

    methods (Access = private)

        % Code that executes after component creation
        function lianxujishu_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to lianxujishu (see VARARGIN)
%             
%             % Choose default command line output for lianxujishu
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            T = str2num(get(app.edit2,'string'));
            A = str2num(get(app.edit3,'string'));
            M = str2num(get(app.edit4,'string'));
            if isempty(T)
                msgbox('input T first','warn','warn');
                return
            elseif T<=0
                msgbox('T should be positive','warn','warn');
                return
            end
            if isempty(A)
                msgbox('input A first','warn','warn');
                return
            elseif A<=0
                msgbox('A should be positive','warn','warn');
                return
            end
            if isempty(M)
                msgbox('input M first','warn','warn');
                return
            elseif M<=0
                msgbox('M should be positive','warn','warn');
                return
            end
            choose = get(app.popupmenu1,'value');
            axes(app.axes1);
            cla;
            if choose == 1
                w0=2*pi/T;
                for i=1:1:M
                    line([(i-1)*T,i*T-T/2],[A,A]);
                    axis([0 M*T -2*A 2*A]);
                    hold on;
                    X=i*T-T/2;
                    Y=-A:A/500:A;
                    line(X,Y);
                    hold on;
                    line([i*T-T/2,i*T],[-A,-A]);
                    hold on;
                    X=i*T;
                    Y=-A:A/500:A;
                    line(X,Y);
                    axis([0 M*T -2*A 2*A]);
                end
            else
                hold off
                for j=1:1:M;
                    t=(j-1.5)*T:T/500:(j-0.5)*T;
                    plot(t,(A-2*A/T*abs(t-(j-1)*T)),'k');
                    hold on;
                 end
            end
        end

        % Button pushed function: pushbutton2
        function pushbutton2_Callback(app, event)
            % hObject    handle to pushbutton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            N = str2num(get(app.edit1,'string'));
            T = str2num(get(app.edit2,'string'));
            A = str2num(get(app.edit3,'string'));
            M = str2num(get(app.edit4,'string'));
            if isempty(T)
                msgbox('input T first','warn','warn');
                return
            elseif T<=0
                msgbox('T should be positive','warn','warn');
                return
            end
            if isempty(A)
                msgbox('input A first','warn','warn');
                return
            elseif A<=0
                msgbox('A should be positive','warn','warn');
                return
            end
            if isempty(M)
                msgbox('input M first','warn','warn');
                return
            elseif M<=0
                msgbox('M should be positive','warn','warn');
                return
            end
            if isempty(N)
                msgbox('input N first','warn','warn');
                return
            elseif (N<=0)||(fix(N)~=N)
                msgbox('N should be positive integer','warn','warn');
                return
            end
            choose = get(app.popupmenu1,'value');
            axes(app.axes2);
            cla;
            if choose == 1
                w0=2*pi/T;
                for i=1:N
                    if i==1
                        stem(0,0,'filled');
                        hold on;
                    else
                        stem(i-1,mod(i+1,2)*4*A/pi/(i-1),'filled');
                        axis([0 N 0 1+fix(4*A/pi)]);
                        hold on;
                    end
                end
            % for m=1:1:M
            %     P=linspace(0,0,N+1);
            %         t=(m-1)*T:T/500:m*T;
            %         plot(t,P([1]),'r');
            %         hold on
            %     for i=2:1:fix(N/2+1.4)
            %         t=(m-1)*T:T/500:m*T;
            %         plot(t,4*A/pi/(2*i-1)*sin((2*i-1)*w0*t),'k');
            %         axis([0 M*T -1.5*A 1.5*A]);
            %         hold on;
            %     end
            % end
            else
                tor=T;w0=2*pi/T;syms t k;
                xet= (A-2*A/T*abs(t))*exp(-j*k* w0*t);
                a=int(xet,t,-tor/2,tor/2)/T;
                n=[-N:N];
                n=n+eps ;
                ak=subs(a,n);
                ak([N+1])=A/2;
                P=linspace(0,0,N+1);
                for i=1:N+1;
                    P([i])=ak([i+N]);
                end
                for i=1:N
                    if i==1
                        stem(0,P([1]),'filled');
                        hold on;
                    else
                        stem(i-1,2*P([i]),'filled');
                        axis([0 N 0 P([1])]);
                        hold on;
                    end
            %     for n=1:1:M
            %         t=(n-1.5)*T:T/500:(n-0.5)*T;
            %         plot(t,P([1]),'r');
            %         hold on
            %     for i=2:1:N
            %         plot(t,2*P([i])*cos((i-1)*w0*t),'k');
            %         axis([-T M*T -0.8*A 0.8*A])
            %         hold on
            %     end
            end
            end
        end

        % Button pushed function: pushbutton3
        function pushbutton3_Callback(app, event)
            % hObject    handle to pushbutton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            N = str2num(get(app.edit1,'string'));
            T = str2num(get(app.edit2,'string'));
            A = str2num(get(app.edit3,'string'));
            M = str2num(get(app.edit4,'string'));
            if isempty(T)
                msgbox('input T first','warn','warn');
                return
            elseif T<=0
                msgbox('T should be positive','warn','warn');
                return
            end
            if isempty(A)
                msgbox('input A first','warn','warn');
                return
            elseif A<=0
                msgbox('A should be positive','warn','warn');
                return
            end
            if isempty(M)
                msgbox('input M first','warn','warn');
                return
            elseif M<=0
                msgbox('M should be positive','warn','warn');
                return
            end
            if isempty(N)
                msgbox('input N first','warn','warn');
                return
            elseif (N<=0)||(fix(N)~=N)
                msgbox('N should be positive integer','warn','warn');
                return
            end
            choose = get(app.popupmenu1,'value');
            axes(app.axes3);
            cla;
            if choose == 1
                w0=2*pi/T;
                t=0:T/500:M*T;
                sum=0;
                for i=1:1:fix((N-1)/2+0.5);
                    sum=sum+4*A/pi/(2*i-1)*sin((2*i-1)*w0*t);
                end
            plot(t,sum,'k');
            axis([0 M*T -2*A 2*A]);
            else
                tor=T;w0=2*pi/T;syms t k;
                xet= (A-2*A/T*abs(t))*exp(-j*k* w0*t);
                a=int(xet,t,-tor/2,tor/2)/T;
                n=[-N:N]; n=n+eps; ak=subs(a,n); ak([N+1])=A/2;
                t=-T/2:T/100:T/2;
                P=linspace(0,0,N+1);
                for i=1:N+1;
                    P([i])=ak([i+N]);
                end
                for n=1:1:M
                    sum=P([1]);
                    for i=2:1:N
                        t=(n-1.5)*T:T/500:(n-0.5)*T;
                        sum=sum+2*P([i])*cos((i-1)*w0*t);
                    end
                    plot(t,sum,'k');
                    axis([-T M*T 0 A])
                    hold on
                 end
            end
        end

        % Button pushed function: pushbutton7
        function pushbutton7_Callback(app, event)
            % hObject    handle to pushbutton7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            close gcf;
            start;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure;
            app.figure1.Name = '连续周期信号的合成与分解';
            app.axes1 = uiaxes(app.figure1);
            app.axes2 = uiaxes(app.figure1);
            app.axes3 = uiaxes(app.figure1);
            app.text1 = uilabel(app.figure1);
            app.text1.Text = '原始信号';
            app.edit1 = uieditfield(app.figure1, 'text');
            app.text5 = uilabel(app.figure1);
            app.text5.Text = '合成信号';
            app.text6 = uilabel(app.figure1);
            app.text6.Text = '谐波分量振幅';
            app.text7 = uilabel(app.figure1);
            app.text7.Text = '谐波分量个数';
            app.pushbutton2 = uibutton(app.figure1, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2_Callback, true);
            app.pushbutton2.Text = '分解系数';
            app.pushbutton3 = uibutton(app.figure1, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_Callback, true);
            app.pushbutton3.Text = '合成';
            app.edit3 = uieditfield(app.figure1, 'text');
            app.edit4 = uieditfield(app.figure1, 'text');
            app.edit2 = uieditfield(app.figure1, 'text');
            app.text8 = uilabel(app.figure1);
            app.text8.Text = '信号幅值A=';
            app.text9 = uilabel(app.figure1);
            app.text9.Text = '显示周期';
            app.text10 = uilabel(app.figure1);
            app.text10.Text = '信号周期';
            app.text12 = uilabel(app.figure1);
            app.text12.Text = '选择信号';
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Text = '波形';
            app.popupmenu1 = uidropdown(app.figure1);
            app.popupmenu1.Items = {'方波', '三角波'};
            app.popupmenu1.Value = '方波';
            app.pushbutton7 = uibutton(app.figure1, 'push');
            app.pushbutton7.ButtonPushedFcn = createCallbackFcn(app, @pushbutton7_Callback, true);
            app.pushbutton7.Text = '返回';
            app.text13 = uilabel(app.figure1);
            app.text13.Text = '信号幅度A';
            app.text14 = uilabel(app.figure1);
            app.text14.Text = '信号幅度';
        end
    end

    methods (Access = public)

        % Construct app
        function app = lianxujishu_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @lianxujishu_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end