classdef lianxuyunsuan_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1              matlab.ui.Figure
        axes1                matlab.ui.control.UIAxes
        axes2                matlab.ui.control.UIAxes
        axes3                matlab.ui.control.UIAxes
        axes4                matlab.ui.control.UIAxes
        axes5                matlab.ui.control.UIAxes
        popupmenu1           matlab.ui.control.DropDown
        text1                matlab.ui.control.Label
        edita1               matlab.ui.control.EditField
        text2                matlab.ui.control.Label
        text3                matlab.ui.control.Label
        editb1               matlab.ui.control.EditField
        popupmenu2           matlab.ui.control.DropDown
        text4                matlab.ui.control.Label
        edita2               matlab.ui.control.EditField
        text5                matlab.ui.control.Label
        text6                matlab.ui.control.Label
        editb2               matlab.ui.control.EditField
        edit5                matlab.ui.control.EditField
        text7                matlab.ui.control.Label
        popupmenu3           matlab.ui.control.DropDown
        text8                matlab.ui.control.Label
        pushbutton1          matlab.ui.control.Button
        pushbutton2          matlab.ui.control.Button
        pushbutton3          matlab.ui.control.Button
        popupmenu4           matlab.ui.control.DropDown
        text9                matlab.ui.control.Label
        uipanel1             matlab.ui.container.Panel
        edit_yiwei           matlab.ui.control.EditField
        text13               matlab.ui.control.Label
        pushbutton_yiwei     matlab.ui.control.Button
        popupmenu_yiwei      matlab.ui.control.DropDown
        text14               matlab.ui.control.Label
        pushbutton4          matlab.ui.control.Button
        text11               matlab.ui.control.Label
        edita3               matlab.ui.control.EditField
        text12               matlab.ui.control.Label
        editb3               matlab.ui.control.EditField
        uipanel3             matlab.ui.container.Panel
        edit_scale           matlab.ui.control.EditField
        text15               matlab.ui.control.Label
        pushbutton_scale     matlab.ui.control.Button
        popupmenu_scale      matlab.ui.control.DropDown
        text16               matlab.ui.control.Label
        pushbutton_fanzhuan  matlab.ui.control.Button
        pushbutton8          matlab.ui.control.Button
        text17               matlab.ui.control.Label
    end

    
    methods (Access = private)
        function edit9_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit9 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc
                set(hObject,'BackgroundColor','white');
            else
                set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function lianxuyunsuan_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to lianxuyunsuan (see VARARGIN)
%             
%             % Choose default command line output for lianxuyunsuan
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global x;
            global t;
            global flag1;
            flag1=1;
            a = str2num(get(app.edita1,'String'));
            b = str2num(get(app.editb1,'String'));
            t=eval(get(app.edit5,'String'));
            choose1 = get(app.popupmenu1,'value');
            min=t([1]);
            max=t([length(t)]);
            guidata(hObject, handles);
            
            % 修改%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            switch choose1
                case 1
                    if (isempty(a)|isempty(b))
                        msgbox('input error','warn','warn');
                        return;
                    end
                    x=a*sin(b*t);
                case 2
                    if (isempty(a)|isempty(b))
                        msgbox('input error','warn','warn');
                        return;
                    end
                    x=a*cos(b*t);
                case 3
                    if (isempty(a)|isempty(b))
                        msgbox('input error','warn','warn');
                        return;
                    end
                    x=a*exp(b*t);
                case 4
                    if (isempty(a)|isempty(b))
                        msgbox('input error','warn','warn');
                        return;
                    end
                    x=a*t+b;
                case 5
                    x=HEAVISIDE(t);
                    flag1=0;
            end
            axes(app.axes1);
            plot(t,x);
            grid on
            if flag1==0
                axis([min max -0.5 1.5])
            end
        end

        % Button pushed function: pushbutton2
        function pushbutton2_Callback(app, event)
            % hObject    handle to pushbutton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global y;
            global t;
            global flag2;
            flag2=1;
            a = str2num(get(app.edita2,'String'));
            b = str2num(get(app.editb2,'String'));
            t=eval(get(app.edit5,'String'));
            choose2 = get(app.popupmenu2,'value');
            guidata(hObject, handles);
            min=t([1]);
            max=t([length(t)]);
            
            % 修改%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            switch choose2
                case 1
                    if (isempty(a)|isempty(b))
                        msgbox('input error','warn','warn');
                        return;
                    end
                    y=a*sin(b*t);
                case 2
                    if (isempty(a)|isempty(b))
                        msgbox('input error','warn','warn');
                        return;
                    end
                    y=a*cos(b*t);
                case 3
                    if (isempty(a)|isempty(b))
                        msgbox('input error','warn','warn');
                        return;
                    end
                    y=a*exp(b*t);
                case 4
                    if (isempty(a)|isempty(b))
                        msgbox('input error','warn','warn');
                        return;
                    end
                    y=a*t+b;
                case 5
                    y=HEAVISIDE(t);
                    flag2=0;
            end
            axes(app.axes2);
            plot(t,y);
            grid on
            if flag2==0
                axis([min max -0.5 1.5])
            end
        end

        % Button pushed function: pushbutton3
        function pushbutton3_Callback(app, event)
            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global x;
            global y;
            global t;
            global flag1;
            global flag2;
            t=eval(get(app.edit5,'String'));
            min=t([1]);
            max=t([length(t)]);
            if isempty(x)
                msgbox('select x1 first','warn','warn');
                return
            end
            if isempty(y)
                 msgbox('select x2 first ','warn','warn');
                return
            end
            choose = get(app.popupmenu3,'value');
            switch choose
                case 1
                    f=x+y;
                case 2
                    f=x.*y;
            end
            axes(app.axes3);
            plot(t,f);
            if (flag1+flag2)==0
                axis([min max -0.5 2.5]);
            end
            grid on
        end

        % Button pushed function: pushbutton4
        function pushbutton4_Callback(app, event)
            % hObject    handle to pushbutton4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global t;
            global flag;
            global Ft;
            flag=1;
            a = str2num(get(app.edita3,'String'));
            b = str2num(get(app.editb3,'String'));
            t=eval(get(app.edit5,'String'));
            choice = get(app.popupmenu4,'value');
            if (isempty(a)|isempty(b))
                 msgbox('input error','warn','warn');
                 return;
            end
            guidata(hObject, handles);
            min=t([1]);
            max=t([length(t)]);
            switch choice
                case 1
                    Ft=a*sin(b*t);
                case 2
                    Ft=a*cos(b*t);
                case 3
                    Ft=a*exp(b*t);
                case 4
                    Ft=a*t+b;
                case 5
                    Ft=HEAVISIDE(t);
                    flag=0;
            end
            axes(app.axes4);
            plot(t,Ft);
            grid on
            if flag==0
                axis([min max -0.5 1.5])
            end
        end

        % Button pushed function: pushbutton8
        function pushbutton8_Callback(app, event)
            % hObject    handle to pushbutton8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            close gcf;
            start;
        end

        % Button pushed function: pushbutton_fanzhuan
        function pushbutton_fanzhuan_Callback(app, event)
            % hObject    handle to pushbutton_fanzhuan (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global t;
            global Ft;
            global flag;
            t=eval(get(app.edit5,'String'));
            min=t([1]);
            max=t([length(t)]);
            if isempty(Ft)
                 msgbox('select x first','warn','warn');
                return
            end
            t=-t;
            axes(app.axes5);
            plot(t,Ft);
            grid on
            if flag==0
                axis([min max -0.5 1.5])
            end
        end

        % Button pushed function: pushbutton_scale
        function pushbutton_scale_Callback(app, event)
            % hObject    handle to pushbutton_scale (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global t;
            global Ft;
            global flag;
            m = str2num(get(app.edit_scale,'String'));
            if (isempty(m))
                 msgbox('input error','warn','warn');
                 return;
            end
            guidata(hObject, handles);
            t=eval(get(app.edit5,'String'));
            choice_scale = get(app.popupmenu_scale,'value');
            min=t([1]);
            max=t([length(t)]);
            if isempty(Ft)
                  msgbox('select x first','warn','warn');
                return
            end
            switch choice_scale
                case 1
                    t=t/m;
                case 2
                    t=t*m;
            end
            axes(app.axes5);
            plot(t,Ft);
            axis([min max -20 20])
            axis 'auto y'
            grid on
            if flag==0
                axis([min max -0.5 1.5])
            end
        end

        % Button pushed function: pushbutton_yiwei
        function pushbutton_yiwei_Callback(app, event)
            % hObject    handle to pushbutton_yiwei (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global t;
            global Ft;
            global flag;
            N = str2num(get(app.edit_yiwei,'String'));
            if (isempty(N))
                 msgbox('input error','warn','warn');
                 return;
            end
            guidata(hObject, handles);
            t=eval(get(app.edit5,'String'));
            choice_yiwei = get(app.popupmenu_yiwei,'value');
            min=t([1]);
            max=t([length(t)]);
            if isempty(Ft)
                 msgbox('select x first','warn','warn');
                return
            end
            switch choice_yiwei
                case 1
                    t=t-N;
                case 2
                    t=t+N;
            end
            axes(app.axes5);
            plot(t,Ft);
            axis([min-N max+N -20 20])
            axis 'auto y'
            grid on
            if flag==0
                axis([min max -0.5 1.5])
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure;
            app.figure1.Name = '连续时间信号的时域运算';
            app.axes1 = uiaxes(app.figure1);
            app.axes2 = uiaxes(app.figure1);
            app.axes3 = uiaxes(app.figure1);
            app.axes4 = uiaxes(app.figure1);
            app.axes5 = uiaxes(app.figure1);
            app.popupmenu1 = uidropdown(app.figure1);
            app.popupmenu1.Items = {'a*sin(b*t)', 'a*cos(b*t)', 'a*exp(b*t)', 'a*t+b', 'u(t)'};
            app.popupmenu1.Value = 'a*sin(b*t)';
            app.text1 = uilabel(app.figure1);
            app.text1.Text = '函数f1';
            app.edita1 = uieditfield(app.figure1, 'text');
            app.text2 = uilabel(app.figure1);
            app.text2.Text = '参数a';
            app.text3 = uilabel(app.figure1);
            app.text3.Text = '参数b';
            app.editb1 = uieditfield(app.figure1, 'text');
            app.popupmenu2 = uidropdown(app.figure1);
            app.popupmenu2.Items = {'a*sin(b*t)', 'a*cos(b*t)', 'a*exp(b*t)', 'a*t+b', 'u(t)'};
            app.popupmenu2.Value = 'a*sin(b*t)';
            app.text4 = uilabel(app.figure1);
            app.text4.Text = '函数x2';
            app.edita2 = uieditfield(app.figure1, 'text');
            app.text5 = uilabel(app.figure1);
            app.text5.Text = '参数a';
            app.text6 = uilabel(app.figure1);
            app.text6.Text = '参数b';
            app.editb2 = uieditfield(app.figure1, 'text');
            app.edit5 = uieditfield(app.figure1, 'text');
            app.edit5.Value = '-10:0.001:10';
            app.text7 = uilabel(app.figure1);
            app.text7.Text = '设置t范围start:inc:end';
            app.popupmenu3 = uidropdown(app.figure1);
            app.popupmenu3.Items = {'+', '*'};
            app.popupmenu3.Value = '+';
            app.text8 = uilabel(app.figure1);
            app.text8.Text = '运算方式';
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Text = {'函数x1图形'; ''};
            app.pushbutton2 = uibutton(app.figure1, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2_Callback, true);
            app.pushbutton2.Text = {'函数x2图形'; ''};
            app.pushbutton3 = uibutton(app.figure1, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_Callback, true);
            app.pushbutton3.Text = {'运算后的函数图形'; ''; ''};
            app.popupmenu4 = uidropdown(app.figure1);
            app.popupmenu4.Items = {'a*sin(b*t)', 'a*cos(b*t)', 'a*exp(b*t)', 'a*t+b', 'u(t)'};
            app.popupmenu4.Value = 'a*sin(b*t)';
            app.text9 = uilabel(app.figure1);
            app.text9.Text = '函数x';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = '平移';
            app.edit_yiwei = uieditfield(app.uipanel1, 'text');
            app.text13 = uilabel(app.uipanel1);
            app.text13.Text = '平移量t0';
            app.pushbutton_yiwei = uibutton(app.uipanel1, 'push');
            app.pushbutton_yiwei.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_yiwei_Callback, true);
            app.pushbutton_yiwei.Text = '平移后图形';
            app.popupmenu_yiwei = uidropdown(app.uipanel1);
            app.popupmenu_yiwei.Items = {'左移', '右移'};
            app.popupmenu_yiwei.Value = '左移';
            app.text14 = uilabel(app.uipanel1);
            app.text14.Text = '平移方式';
            app.pushbutton4 = uibutton(app.figure1, 'push');
            app.pushbutton4.ButtonPushedFcn = createCallbackFcn(app, @pushbutton4_Callback, true);
            app.pushbutton4.Text = {'函数x图形'; ''};
            app.text11 = uilabel(app.figure1);
            app.text11.Text = '参数a';
            app.edita3 = uieditfield(app.figure1, 'text');
            app.text12 = uilabel(app.figure1);
            app.text12.Text = '参数b';
            app.editb3 = uieditfield(app.figure1, 'text');
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = '尺度变换';
            app.edit_scale = uieditfield(app.uipanel3, 'text');
            app.text15 = uilabel(app.uipanel3);
            app.text15.Text = '变换因子m';
            app.pushbutton_scale = uibutton(app.uipanel3, 'push');
            app.pushbutton_scale.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_scale_Callback, true);
            app.pushbutton_scale.Text = '变换后图形';
            app.popupmenu_scale = uidropdown(app.uipanel3);
            app.popupmenu_scale.Items = {'压缩', '拉伸'};
            app.popupmenu_scale.Value = '压缩';
            app.text16 = uilabel(app.uipanel3);
            app.text16.Text = '变换方式';
            app.pushbutton_fanzhuan = uibutton(app.figure1, 'push');
            app.pushbutton_fanzhuan.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_fanzhuan_Callback, true);
            app.pushbutton_fanzhuan.Text = '函数反转';
            app.pushbutton8 = uibutton(app.figure1, 'push');
            app.pushbutton8.ButtonPushedFcn = createCallbackFcn(app, @pushbutton8_Callback, true);
            app.pushbutton8.Text = '返回';
            app.text17 = uilabel(app.figure1);
            app.text17.Text = '函数x1';
        end
    end

    methods (Access = public)

        % Construct app
        function app = lianxuyunsuan_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @lianxuyunsuan_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end